classdef ControlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image3                    matlab.ui.control.Image
        EstopTriggerLamp          matlab.ui.control.Lamp
        StatewindowEditField      matlab.ui.control.EditField
        ControllerStateLamp       matlab.ui.control.Lamp
        Image2                    matlab.ui.control.Image
        ShutDownButton            matlab.ui.control.Button
        SafetyTestingPanel        matlab.ui.container.Panel
        VisualServoingDemoButton  matlab.ui.control.Button
        ControlPanelLabel         matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        UR3LinksTab               matlab.ui.container.Tab
        Image5                    matlab.ui.control.Image
        ResetButton               matlab.ui.control.Button
        Link6Knob                 matlab.ui.control.Knob
        Link6KnobLabel            matlab.ui.control.Label
        Link5Knob                 matlab.ui.control.Knob
        Link5KnobLabel            matlab.ui.control.Label
        Link4Knob                 matlab.ui.control.Knob
        Link4KnobLabel            matlab.ui.control.Label
        Link3Knob                 matlab.ui.control.Knob
        Link3KnobLabel            matlab.ui.control.Label
        Link2Knob                 matlab.ui.control.Knob
        Link2KnobLabel            matlab.ui.control.Label
        Link1Knob                 matlab.ui.control.Knob
        Link1KnobLabel            matlab.ui.control.Label
        PandaLinksTab             matlab.ui.container.Tab
        Link7Knob                 matlab.ui.control.Knob
        Link7KnobLabel            matlab.ui.control.Label
        Link6Knob_2               matlab.ui.control.Knob
        Link6Knob_2Label          matlab.ui.control.Label
        Link5Knob_2               matlab.ui.control.Knob
        Link5Knob_2Label          matlab.ui.control.Label
        Link4Knob_2               matlab.ui.control.Knob
        Link4Knob_2Label          matlab.ui.control.Label
        Link3Knob_2               matlab.ui.control.Knob
        Link3Knob_2Label          matlab.ui.control.Label
        Link2Knob_2               matlab.ui.control.Knob
        Link2Knob_2Label          matlab.ui.control.Label
        Link1Knob_2               matlab.ui.control.Knob
        Link1Knob_2Label          matlab.ui.control.Label
        UR3CartesianControlTab    matlab.ui.container.Tab
        ZSlider                   matlab.ui.control.Slider
        ZSliderLabel              matlab.ui.control.Label
        YSlider                   matlab.ui.control.Slider
        YSliderLabel              matlab.ui.control.Label
        XSlider                   matlab.ui.control.Slider
        XSliderLabel              matlab.ui.control.Label
        PandaCartesianControlTab  matlab.ui.container.Tab
        ZSlider_2                 matlab.ui.control.Slider
        ZSlider_2Label            matlab.ui.control.Label
        YSlider_2                 matlab.ui.control.Slider
        YSlider_2Label            matlab.ui.control.Label
        XSlider_2                 matlab.ui.control.Slider
        XSlider_2Label            matlab.ui.control.Label
        Image_2                   matlab.ui.control.Image
        EmergencyStopButton       matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        StartButton               matlab.ui.control.Button
    end

     properties (Access = public)
        ur3; 
        panda;
        ur3StartQ;
        ur3UpdateQ;
        ur3control;
        ur3finger1;
        ur3finger2;
        pandaStartQ;
        pandaUpdateQ;
        pandafinger1;
        pandafinger2;
        pandacontrol;
        resolvedMotionRateControl;
        kitchen;
        plates;
        plateCount;
        platePosition;
        plateHeight;
        ur3base;
        ur3Link1 = 0;
        ur3Link2 = 0;
        ur3Link3 = 0;
        ur3Link4 = 0;
        ur3Link5 = 0;
        ur3Link6 = 0;
        pandabase;
        pandaLink1 = 0;
        pandaLink2 = 0;
        pandaLink3 = 0;
        pandaLink4 = 0;
        pandaLink5 = 0;
        pandaLink6 = 0;
        pandaLink7 = 0;
        ur3velocityx = 0;
        ur3velocityy = 0;
        ur3velocityz = 0;
        pandavelocityx = 0;
        pandavelocityy = 0;
        pandavelocityz = 0;
        hand;
        time;
        deltaT;
        lightcurtain;
        ur3T1;
        ur3T2;
        ur3T3;
        ur3T4;
        pandaT1;
        pandaT2;
        pandaT3;
        pandaT4;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            % Display initialisation prompts into the panel
            app.StatewindowEditField.Value = "Starting up panel......";
            app.ControllerStateLamp.Color = [0.8 0.8 0.8]; % White
            pause(0.5)
            app.StatewindowEditField.Value = "Loading UI....";

            % Spawn Environment
            app.kitchen = Kitchen(0);          
            app.kitchen.Spawnkitchen(); 
            
            % Plot UR3 
            cupboardheight = 1.0;
            app.ur3base.position = [0.8, -1.85, cupboardheight];
            app.ur3 = ModifiedUR3(app.ur3base.position);
            app.ur3.PlotAndColourUR3Robot();
            
            % Plot RobotIQ gripper
%             ur3_q0 = zeros (1, app.ur3.model.n); % Initial UR3 joint angles
           
%             % IQ Finger 1
%             app.ur3finger1 = IQFinger1;
%             app.ur3finger1.model.base = app.ur3finger1.model.base * app.ur3.model.fkine(ur3_q0) * troty(-pi/2) * transl(0.065,-0.0115,0); % Forward kinematics for end-effector base
%             app.ur3finger1.GetAndColourFingerIQ1(); 
%             
%             % IQ Finger 2
%             app.ur3finger2 = IQFinger2;
%             app.ur3finger2.model.base = app.ur3finger2.model.base * app.ur3.model.fkine(ur3_q0) * trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
%             app.ur3finger2.GetAndColourFingerIQ2();  
            
            % Plot Panda 
            tableheight = 0.97875;
            app.pandabase.position = [-0.15, -1.45, tableheight];
            app.panda = Panda(app.pandabase.position);
            app.panda.PlotAndColourPandaRobot();
            
            % Plot Panda gripper
%             panda_q0 = zeros (1, app.panda.model.n); % Initial Panda joint angles
           
            % Panda Finger 1
%             app.pandafinger1 = FingerPanda1;
%             app.pandafinger1.model.base = app.pandafinger1.model.base * app.panda.model.fkine(panda_q0) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2); 
%             app.pandafinger1.GetAndColourFingerPanda1(); 
%             
%             % Panda Finger 2
%             app.pandafinger2 = FingerPanda2;
%             app.pandafinger2.model.base = app.pandafinger2.model.base * app.panda.model.fkine(panda_q0) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi); 
%             app.pandafinger2.GetAndColourFingerPanda2();  
            
            % Plot the plates
            app.plateCount = 2; % Count of plates spawned in the environment
            app.platePosition = [0.75,-1.525; ... % Plate 1 position
                                 0.75,-1.45];     % Plate 2 position
              
            for i = 1:app.plateCount % Generating 2 plates
                app.plates{i} = Plate(i,app.platePosition(i,1),app.platePosition(i,2),0); % Constructor: Plate(plateCount,x,y,setPos)
            end
            
            view(170,15); % Rotate the scene
            app.StatewindowEditField.Value = "Successfully connected to Control Panel";
            app.ControllerStateLamp.Color = [0 1 0]; % Green
%             app.EnableCollisionAvoidanceButtonValueChanged; % Always have collision avoidance active
        end

        % Value changing function: Link6Knob
        function Link6KnobValueChanging(app, event)
            changingValue = event.Value;
            app.ur3Link6 = deg2rad(changingValue);
            app.ur3.model.animate([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]);
%             app.ur3finger1.model.base = app.ur3.model.fkine([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]) * troty(-pi/2) * transl(0.065,-0.0115,0); % Forward kinematics for end-effector base
%             app.ur3finger1.model.animate([0 0]);
%             app.ur3finger2.model.base = app.ur3.model.fkine([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]) * trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
%             app.ur3finger2.model.animate([0 0]);
%             app.pandafinger1.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2);
%             app.pandafinger1.model.animate(0);
%             app.pandafinger2.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi);
%             app.pandafinger2.model.animate(0);
        end

        % Value changing function: Link5Knob
        function Link5KnobValueChanging(app, event)
            changingValue = event.Value;
            app.ur3Link5 = deg2rad(changingValue);
            app.ur3.model.animate([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]);
%             app.ur3finger1.model.base = app.ur3.model.fkine([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]) * troty(-pi/2) * transl(0.065,-0.0115,0); % Forward kinematics for end-effector base
%             app.ur3finger1.model.animate([0 0]);
%             app.ur3finger2.model.base = app.ur3.model.fkine([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]) * trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
%             app.ur3finger2.model.animate([0 0]);
%             app.pandafinger1.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2);
%             app.pandafinger1.model.animate(0);
%             app.pandafinger2.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi);
%             app.pandafinger2.model.animate(0);
        end

        % Value changing function: Link4Knob
        function Link4KnobValueChanging(app, event)
            changingValue = event.Value;
            app.ur3Link4 = deg2rad(changingValue);
            app.ur3.model.animate([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]);
%             app.ur3finger1.model.base = app.ur3.model.fkine([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]) * troty(-pi/2) * transl(0.065,-0.0115,0); % Forward kinematics for end-effector base
%             app.ur3finger1.model.animate([0 0]);
%             app.ur3finger2.model.base = app.ur3.model.fkine([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]) * trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
%             app.ur3finger2.model.animate([0 0]);
%             app.pandafinger1.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2);
%             app.pandafinger1.model.animate(0);
%             app.pandafinger2.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi);
%             app.pandafinger2.model.animate(0);
        end

        % Value changing function: Link3Knob
        function Link3KnobValueChanging(app, event)
            changingValue = event.Value;
            app.ur3Link3 = deg2rad(changingValue);
            app.ur3.model.animate([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]);
%             app.ur3finger1.model.base = app.ur3.model.fkine([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]) * troty(-pi/2) * transl(0.065,-0.0115,0); % Forward kinematics for end-effector base
%             app.ur3finger1.model.animate([0 0]);
%             app.ur3finger2.model.base = app.ur3.model.fkine([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]) * trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
%             app.ur3finger2.model.animate([0 0]);
%             app.pandafinger1.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2);
%             app.pandafinger1.model.animate(0);
%             app.pandafinger2.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi);
%             app.pandafinger2.model.animate(0);
        end

        % Value changing function: Link2Knob
        function Link2KnobValueChanging(app, event)
            changingValue = event.Value;
            app.ur3Link2 = deg2rad(changingValue);
            app.ur3.model.animate([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]);
%             app.ur3finger1.model.base = app.ur3.model.fkine([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]) * troty(-pi/2) * transl(0.065,-0.0115,0); % Forward kinematics for end-effector base
%             app.ur3finger1.model.animate([0 0]);
%             app.ur3finger2.model.base = app.ur3.model.fkine([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]) * trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
%             app.ur3finger2.model.animate([0 0]);
%             app.pandafinger1.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2);
%             app.pandafinger1.model.animate(0);
%             app.pandafinger2.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi);
%             app.pandafinger2.model.animate(0);
        end

        % Value changing function: Link1Knob
        function Link1KnobValueChanging(app, event)
            changingValue = event.Value;
            app.ur3Link1 = deg2rad(changingValue);
            app.ur3.model.animate([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]);
%             app.ur3finger1.model.base = app.ur3.model.fkine([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]) * troty(-pi/2) * transl(0.065,-0.0115,0); % Forward kinematics for end-effector base
%             app.ur3finger1.model.animate([0 0]);
%             app.ur3finger2.model.base = app.ur3.model.fkine([app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]) * trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
%             app.ur3finger2.model.animate([0 0]);
%             app.pandafinger1.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2);
%             app.pandafinger1.model.animate(0);
%             app.pandafinger2.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi);
%             app.pandafinger2.model.animate(0);
        end

        % Value changing function: Link7Knob
        function Link7KnobValueChanging(app, event)
            changingValue = event.Value;
            app.pandaLink7 = deg2rad(changingValue);
            app.panda.model.animate([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]);
%             app.pandafinger1.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2);
%             app.pandafinger1.model.animate(0);
%             app.pandafinger2.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi);
%             app.pandafinger2.model.animate(0);
        end

        % Value changing function: Link6Knob_2
        function Link6Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandaLink6 = deg2rad(changingValue);
            app.panda.model.animate([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]);
%             app.pandafinger1.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2);
%             app.pandafinger1.model.animate(0);
%             app.pandafinger2.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi);
%             app.pandafinger2.model.animate(0);
        end

        % Value changing function: Link5Knob_2
        function Link5Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandaLink5 = deg2rad(changingValue);
            app.panda.model.animate([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]);
%             app.pandafinger1.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2);
%             app.pandafinger1.model.animate(0);
%             app.pandafinger2.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi);
%             app.pandafinger2.model.animate(0);
        end

        % Value changing function: Link4Knob_2
        function Link4Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandaLink4 = deg2rad(changingValue);
            app.panda.model.animate([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]);
%             app.pandafinger1.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2);
%             app.pandafinger1.model.animate(0);
%             app.pandafinger2.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi);
%             app.pandafinger2.model.animate(0);
        end

        % Value changing function: Link3Knob_2
        function Link3Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandaLink3 = deg2rad(changingValue);
            app.panda.model.animate([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]);
%             app.pandafinger1.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2);
%             app.pandafinger1.model.animate(0);
%             app.pandafinger2.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi);
%             app.pandafinger2.model.animate(0);
        end

        % Value changing function: Link2Knob_2
        function Link2Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandaLink2 = deg2rad(changingValue);
            app.panda.model.animate([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]);
%             app.pandafinger1.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2);
%             app.pandafinger1.model.animate(0);
%             app.pandafinger2.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi);
%             app.pandafinger2.model.animate(0);
        end

        % Value changing function: Link1Knob_2
        function Link1Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandaLink1 = deg2rad(changingValue);
            app.panda.model.animate([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]);
%             app.pandafinger1.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2);
%             app.pandafinger1.model.animate(0);
%             app.pandafinger2.model.base = app.panda.model.fkine([app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6,app.pandaLink7]) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi);
%             app.pandafinger2.model.animate(0);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StatewindowEditField.Value = "Starting RMRC sequence....";
            app.ur3StartQ = [app.ur3Link1,app.ur3Link2,app.ur3Link3,app.ur3Link4,app.ur3Link5,app.ur3Link6]; % Reasonable initial guess for ikcon in control
            app.pandaStartQ = [app.pandaLink1,app.pandaLink2,app.pandaLink3,app.pandaLink4,app.pandaLink5,app.pandaLink6, app.pandaLink7];
            % Animate each joint configuration to the set values from the knobs
            app.ur3.model.animate(app.ur3StartQ);
            app.panda.model.animate(app.pandaStartQ);
            
            % RMRC Trajectory
            app.plateHeight = 1.05;
            app.time = 10;                                                  % Total time (s)
            app.deltaT = 0.05;                                              % Control frequency
            app.ur3T1 = app.ur3.model.fkine(app.ur3StartQ);                 % Initial transform
            app.pandaT1 = app.panda.model.fkine(app.pandaStartQ);
            app.ur3T2 = transl(0.8,-1.4, app.plateHeight) * trotx(pi) * trotz(pi/2);  % Arm Up position (trot to rotate the end-effector orient
 
            app.resolvedMotionRateControl = true;                           % RMRC to Plate Position
            app.ur3control = RMRC(app.ur3, app.resolvedMotionRateControl);
            app.ur3control.ResolvedMotionRateControl(app.ur3T1,app.ur3T2,app.time,app.deltaT);  % towards plate
              
            % From plate to dish rack (ur3)
            app.ur3T3 = transl(0.1725,-2.0,1.3) * trotx(pi) * trotz(pi/2);
            app.ur3control.ResolvedMotionRateControl(app.ur3T2,app.ur3T3,app.time,app.deltaT);  % towards plate
            
            % Move up after drop
            app.ur3T4 = transl(0.8,-1.85,2);
            app.ur3control.ResolvedMotionRateControl(app.ur3T3,app.ur3T4,app.time,app.deltaT);  % towards plate
            
            % From dish rack to cupboard (panda)
            app.pandaT1 = app.panda.model.fkine(app.pandaStartQ);
            app.pandaT2 = transl(0.1725,-1.8,1.3) * trotx(pi/2);
            app.pandacontrol = RMRC(app.panda, app.resolvedMotionRateControl);
            app.pandacontrol.ResolvedMotionRateControl(app.pandaT1,app.pandaT2,app.time,app.deltaT);
            
            % From plate to dish rack
            app.pandaT3 = transl(-0.7,-2.1,1.7) * trotx(pi/2);
            app.pandacontrol.ResolvedMotionRateControl(app.pandaT2,app.pandaT3,app.time,app.deltaT);  % towards plate
                      
            % From plate to dish rack
            app.pandaT4 = transl(-0.05,-1.45,1.9) * trotx(-pi/2) * trotz(pi); % Base Position
            app.pandacontrol.ResolvedMotionRateControl(app.pandaT3,app.pandaT4,app.time,app.deltaT);  % towards plate
           
            app.StatewindowEditField.Value = "RMRC sequence finished";
            

        end

        % Button pushed function: ShutDownButton
        function ShutDownButtonPushed(app, event)
            app.StatewindowEditField.Value = "Simulation ended. Motion is finished";
            clf;
            cla;
            clear;
            close all;
            clc;
            closereq;
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            app.EstopTriggerLamp.Color = [0 1 0]; % Green
            app.StatewindowEditField.Value = "E-stop pushed";
            set(gcf,'color','r'); % Set background color to red
            pause;
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            app.EmergencyStopButtonPushed;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.StartButtonPushed;
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            app.ShutDownButtonPushed;
        end

        % Callback function
        function ResumeButtonPushed(app, event)
            
        end

        % Callback function
        function Image4Clicked(app, event)
            app.ResumeButtonPushed;
        end

        % Callback function
        function Image5Clicked(app, event)
            app.ResetButtonPushed;
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ur3velocityz= 0.3 * changingValue;
            dt = 0.30;
            dx = [app.ur3velocityx;app.ur3velocityy;app.ur3velocityz;0;0;0]; % [x,y,z,roll,pitch,yaw]
            q = app.ur3.model.getpos;
            J = app.ur3.model.jacob0(q);
            dq = pinv(J)* dx;
            q = q + dq'*dt;
            app.ur3.model.animate(q);
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ur3velocityy= 0.3 * changingValue;
            dt = 0.30;
            dx = [app.ur3velocityx;app.ur3velocityy;app.ur3velocityz;0;0;0]; % [x,y,z,roll,pitch,yaw]
            q = app.ur3.model.getpos;
            J = app.ur3.model.jacob0(q);
            dq = pinv(J)* dx;
            q = q + dq'*dt;
            app.ur3.model.animate(q);
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ur3velocityx= 0.3 * changingValue;
            dt = 0.30;
            dx = [app.ur3velocityx;app.ur3velocityy;app.ur3velocityz;0;0;0]; % [x,y,z,roll,pitch,yaw]
            q = app.ur3.model.getpos;
            J = app.ur3.model.jacob0(q);
            dq = pinv(J)* dx;
            q = q + dq'*dt;
            app.ur3.model.animate(q);
        end

        % Value changing function: ZSlider_2
        function ZSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandavelocityz= 0.3 * changingValue;
            dt = 0.30;
            dx = [app.pandavelocityx;app.pandavelocityy;app.pandavelocityz;0;0;0]; % [x,y,z,roll,pitch,yaw]
            q = app.panda.model.getpos;
            J = app.panda.model.jacob0(q);
            dq = pinv(J) * dx;
            q = q + dq'*dt;
            app.panda.model.animate(q);
        end

        % Value changing function: YSlider_2
        function YSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandavelocityy= 0.3 * changingValue;
            dt = 0.30;
            dx = [app.pandavelocityx;app.pandavelocityy;app.pandavelocityz;0;0;0]; % [x,y,z,roll,pitch,yaw]
            q = app.panda.model.getpos;
            J = app.panda.model.jacob0(q);
            dq = pinv(J)* dx;
            q = q + dq'*dt;
            app.panda.model.animate(q);
        end

        % Value changing function: XSlider_2
        function XSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pandavelocityx= 0.3 * changingValue;
            dt = 0.30;
            dx = [app.pandavelocityx;app.pandavelocityy;app.pandavelocityz;0;0;0];
            q = app.panda.model.getpos;
            J = app.panda.model.jacob0(q);
            dq = pinv(J)* dx;
            q = q + dq'*dt;
            app.panda.model.animate(q);
        end

        % Callback function
        function VisualServoingButtonPushed(app, event)
 
        end

        % Callback function
        function LightCurtainDemoButtonPushed(app, event)
            clf;
            app.StatewindowEditField.Value = "Initialising Light Curtain Demo....";
            app.EstopTriggerLamp.Color = [0.8 0.8 0.8];
            app.kitchen = Kitchen(0); % Plot the environment
            app.kitchen.Spawnkitchen(); % Plot the environment objects
 
        % Generate pates
        app.plateCount = 2; % Count of plates spawned in the environment
        app.platePosition = [0.75,-1.525; ... % Plate 1 position
                         0.75,-1.45]; % Plate 2 position
              
  for i = 1:app.plateCount % Generating 2 plates
    app.plates{i} = Plate(i,app.platePosition(i,1),app.platePosition(i,2),0); % Plate Constructor
  end
  
 %% Robots
 tableheight = 0.97875;
 pandaBaseTransform = [-0.15, -1.45, tableheight]; % Panda robot Base position
 ur3BaseTransform = [0.8,-1.875,1.015]; % UR3 Base position
 
 % Plot the UR3
 app.ur3 = ModifiedUR3(ur3BaseTransform);
 app.ur3.PlotAndColourUR3Robot()
%  ur3_q0 = zeros(1, app.ur3.model.n);
%  
%   % IQ Finger 1
%             app.ur3finger1 = IQFinger1;
%             app.ur3finger1.model.base = app.ur3finger1.model.base * app.ur3.model.fkine(ur3_q0) * troty(-pi/2) * transl(0.065,-0.0115,0); % Forward kinematics for end-effector base
%             app.ur3finger1.GetAndColourFingerIQ1(); 
%             
%             % IQ Finger 2
%             app.ur3finger2 = IQFinger2;
%             app.ur3finger2.model.base = app.ur3finger2.model.base * app.ur3.model.fkine(ur3_q0) * trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
%             app.ur3finger2.GetAndColourFingerIQ2();  
 
 % Plot the Panda
 app.panda = Panda(pandaBaseTransform);
 app.panda.PlotAndColourPandaRobot()
%  panda_q0 = zeros(1, app.panda.model.n);
%  
%              % Panda Finger 1
%             app.pandafinger1 = FingerPanda1;
%             app.pandafinger1.model.base = app.pandafinger1.model.base * app.panda.model.fkine(panda_q0) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2); 
%             app.pandafinger1.GetAndColourFingerPanda1(); 
%             
%             % Panda Finger 2
%             app.pandafinger2 = FingerPanda2;
%             app.pandafinger2.model.base = app.pandafinger2.model.base * app.panda.model.fkine(panda_q0) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi); 
%             app.pandafinger2.GetAndColourFingerPanda2();  
            
 % Plot the Hand
 app.hand = Hand(0);
 app.hand.base = app.hand.base * transl(0,-1,-2); % [X,Z,Y]
 app.hand.PlotAndColourHand() % Hand initial position

 % Initiate Light Curtain Demo
 input('/nPress Start')
 [x,z] = meshgrid(-1.6:0.01:1.7, 0:0.01:1.5);  %setting location of meshgrid
 y(1:size(x,1),1:1) = 0.2;
 app.lightcurtain = surf(x,y,z,'FaceAlpha',0.1,'EdgeColor','none');

 [~,v,~] = plyread('hand.ply','tri'); % f and data values not necessary
 handvertices = v;

 input('\nInitiate Light curtain Demo')

 h.hand.base = transl(0,-0.2,1) * trotx(pi/2) * trotz(pi);
 h.hand.animate(h.hand.getpos);
 drawnow();
 pause(0.2);

 handvertices(:,1) = handvertices(:,1) + h.hand.base(1,4);
 handvertices(:,2) = handvertices(:,2) + h.hand.base(2,4);
 handvertices(:,3) = handvertices(:,3) + h.hand.base(3,4);

 if max(handvertices(:,2)) >= -0.5
    app.EmergencyStopButtonPushed();
    app.EstopTriggerLamp.Color = [0 1 0];
    set(gcf,'color','r')
    app.StatewindowEditField.Value = "Object sensed in the robot's vacinity, activate Emergency Stop";
 end

        end

        % Button pushed function: VisualServoingDemoButton
        function VisualServoingDemoButtonPushed(app, event)
            clf;
            app.StatewindowEditField.Value = "Initialising Visual Servoing simulation....";
            pause(0.1);
            
             app.kitchen = Kitchen(0); % Plot the environment
             app.kitchen.Spawnkitchen(); % Plot the environment objects
            
             plate_h = PlaceObject('plate.PLY',[0.265,-1.91,1.125]);
             verts = [get(plate_h ,'Vertices'), ones(size(get(plate_h ,'Vertices'),1),1)];
             set(plate_h ,'Vertices',verts(:,1:3)) % Plate spawned in the environment
             
            %% 1.1 Definitions
            % Create image target (points in the image plane) 
            pStar = [325 200 200 325; 250 250 375 375]; % Points of the plate plane (Target)
            
            %Create 3D points
            P = [0.1,0.1,0.1,0.1;
            -1.75,-1.65,-1.65,-1.75;
             0.9,0.9,1.0,1.0];
            
            % Make a UR3
            ur3BaseTransform = [0.85,-2.125,1.0]; % Set UR3 Base
            app.ur3 = ModifiedUR3(ur3BaseTransform);  
            app.ur3.PlotAndColourUR3Robot()
            
            % Make a Panda
            tableheight = 0.97875;
            pandaBaseTransform = [-0.15,-1.45,tableheight]; % Set UR3 Base
            app.panda = Panda(pandaBaseTransform);
            app.panda.PlotAndColourPandaRobot();
                        
            %Initial pose
            q0 = deg2rad([90;-45;60;0;0;0]);
            
            % Add the camera
            name = 'UR3cam';
            cam = CentralCamera('focal', 0.08, 'pixel', 10e-5, ...
            'resolution', [1024 1024], 'centre', [512 512],'name', name);
            
            % frame rate
            fps = 25;
            
            %Define values
            %gain of the controler
            lambda = 2.5;
            %depth of the IBVS
            depth = mean (P(1,:));
            %% 1.2 Initialise Simulation (Display in 3D)
            %Display UR3
            Tc0 = app.ur3.model.fkine(q0);
            app.ur3.model.animate(q0');
            drawnow();
            
            % plot camera and points
            cam.T = Tc0; % Assign the location of the end-effector to the camera 
            
            % Display points in 3D and the camera
            cam.plot_camera('Tcam', Tc0, 'label','scale',0.05);
            % plot_sphere(P, 0.015, 'b')
            lighting gouraud
            light
            %% 1.3 Initialise Simulation (Display in Image view)
            %Project points to the image
            p = cam.plot(P, 'Tcam', Tc0);
            
            %camera view and plotting
            cam.clf()
            cam.plot(pStar, '*'); % plot the target points in the camera view
            cam.hold(true);
            cam.plot(P, 'Tcam', Tc0, 'o'); % plot the current points in the camera view
            pause(2)
            cam.hold(true);
            cam.plot(P);    % show initial view
            
            view(170,15); % Rotate the camera view appropriately
            
            %Initialise display arrays
            vel_p = [];
            uv_p = [];
            history = [];
            %% 1.4 Loop
            
            input('Track plate');
            
            % loop of the visual servoing (all erros are calculated in the image plane)
            ksteps = 0;
             while true
                    ksteps = ksteps + 1;
                    
                    % compute the view of the camera
                    uv = cam.plot(P);
                    
                    % compute image plane error as a column
                    e = pStar-uv;   % feature error
                    e = e(:);
                    Zest = [];   % an estimate of Z is made as we are assuming that we roughly know the location of the target
                    
                    % compute the Jacobian
                    if isempty(depth)
                        % exact depth from simulation (not possible in practice)
                        pt = homtrans(inv(Tcam), P);
                        J = cam.visjac_p(uv, pt(3,:));
                    elseif ~isempty(Zest)
                        J = cam.visjac_p(uv, Zest);
                    else
                        J = cam.visjac_p(uv, depth);
                    end
            
                    % compute the velocity of camera in camera frame
                    try
                        v = lambda * pinv(J) * e;
                    catch
                        status = -1;
                        return
                    end
                    fprintf('v: %.3f %.3f %.3f %.3f %.3f %.3f\n', v); % Print the velocities
            
                    %compute robot's Jacobian and inverse
                    J2 = app.ur3.model.jacobn(q0);
                    Jinv = pinv(J2);  % ? difference between inv and pinv
                    qp = Jinv*v;
                     
                     %Maximum angular velocity cannot exceed 180 degrees/s
                     ind=find(qp>pi);
                     if ~isempty(ind)
                         qp(ind)=pi;
                     end
                     ind=find(qp<-pi);
                     if ~isempty(ind)
                         qp(ind)=-pi;
                     end
            
                    %Update joints 
                    q = q0 + (1/fps)* qp;
                    app.ur3.model.animate(q');
            
                    %Get camera location
                    Tc = app.ur3.model.fkine(q);
                    cam.T = Tc; % ? Assign the location of the end-effector to the camera
            
                    drawnow();
                    
                    % update the history variables
                    hist.uv = uv(:);
                    vel = v;
                    hist.vel = vel;
                    hist.e = e;
                    hist.en = norm(e);
                    hist.jcond = cond(J);
                    hist.Tcam = Tc;
                    hist.vel_p = vel;
                    hist.uv_p = uv;
                    hist.qp = qp;
                    hist.q = q;
            
                    history = [history hist];
            
                     pause(1/fps)
            
                    if ~isempty(200) && (ksteps > 200)
                        break;
                    end
                    
                    %update current joint position
                    q0 = q;
             end %loop finishes
             app.StatewindowEditField.Value = "Visual Servoing finished";
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % move plates back to original position
            app.platePosition = [0.75,-1.525; ... % Plate 1 position
                                 0.75,-1.45];     % Plate 2 position
            basketHeight = 0.8;
            for i = 1:app.plateCount % for each plate (should only be the 2), put it back to its starting position
                app.plates{i}.plate.base(:,4) = [app.platePosition(i,1); app.platePosition(i,2); basketHeight; 1];  
            end
            
            % move robots back to original position
            app.ur3StartQ = zeros(1,6); % just zero for all ur3 joints
            app.pandaStartQ = zeros(1,7); % zero for all panda joints
            app.ur3.model.animate(app.ur3StartQ); % set the ur3 to zero
            app.panda.model.animate(app.pandaStartQ); % set panda to zero
%             app.ur3finger1.model.base = app.ur3.model.fkine(app.ur3StartQ) * troty(-pi/2) * transl(0.065,-0.0115,0); % Forward kinematics for end-effector base
%             app.ur3finger1.model.animate([pi/6 0]);
%             app.ur3finger2.model.base = app.ur3.model.fkine(app.ur3StartQ) * trotz(pi) * troty(-pi/2) * transl(0.065,-0.0115,0);
%             app.ur3finger2.model.animate([pi/6 0]);
%             app.pandafinger1.model.base = app.panda.model.fkine(app.pandaStartQ) * transl(0,-0.1,0.015) * trotz(-pi/2) * trotx(pi/2);
%             app.pandafinger1.model.animate(0);
%             app.pandafinger2.model.base = app.panda.model.fkine(app.pandaStartQ) * transl(-0.15,-0.1,-0.015) * trotz(-pi/2) * trotx(pi/2) * trotx(pi);
%             app.pandafinger2.model.animate(0);
            drawnow() % make sure the scene updates
            app.StatewindowEditField.Value = "Simulation Successfully Reset.";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'Play_button.png';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = 'Estop.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UR3LinksTab = uitab(app.TabGroup);
            app.UR3LinksTab.Title = 'UR3 Links';
            app.Link1KnobLabel = uilabel(app.UR3LinksTab);
            app.Link1KnobLabel.Text = 'Link 1';
            app.Link1Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link1Knob.Limits = [-360 360];
            app.Link1Knob.ValueChangingFcn = createCallbackFcn(app, @Link1KnobValueChanging, true);
            app.Link2KnobLabel = uilabel(app.UR3LinksTab);
            app.Link2KnobLabel.Text = 'Link 2';
            app.Link2Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link2Knob.Limits = [-360 360];
            app.Link2Knob.ValueChangingFcn = createCallbackFcn(app, @Link2KnobValueChanging, true);
            app.Link3KnobLabel = uilabel(app.UR3LinksTab);
            app.Link3KnobLabel.Text = 'Link 3';
            app.Link3Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link3Knob.Limits = [-360 360];
            app.Link3Knob.ValueChangingFcn = createCallbackFcn(app, @Link3KnobValueChanging, true);
            app.Link4KnobLabel = uilabel(app.UR3LinksTab);
            app.Link4KnobLabel.Text = 'Link 4';
            app.Link4Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link4Knob.Limits = [-360 360];
            app.Link4Knob.ValueChangingFcn = createCallbackFcn(app, @Link4KnobValueChanging, true);
            app.Link5KnobLabel = uilabel(app.UR3LinksTab);
            app.Link5KnobLabel.Text = 'Link 5';
            app.Link5Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link5Knob.Limits = [-360 360];
            app.Link5Knob.ValueChangingFcn = createCallbackFcn(app, @Link5KnobValueChanging, true);
            app.Link6KnobLabel = uilabel(app.UR3LinksTab);
            app.Link6KnobLabel.Text = 'Link 6';
            app.Link6Knob = uiknob(app.UR3LinksTab, 'continuous');
            app.Link6Knob.Limits = [-360 360];
            app.Link6Knob.ValueChangingFcn = createCallbackFcn(app, @Link6KnobValueChanging, true);
            app.ResetButton = uibutton(app.UR3LinksTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image5 = uiimage(app.UR3LinksTab);
            app.Image5.ImageSource = 'Reset.jpg';
            app.PandaLinksTab = uitab(app.TabGroup);
            app.PandaLinksTab.Title = 'Panda Links';
            app.Link1Knob_2Label = uilabel(app.PandaLinksTab);
            app.Link1Knob_2Label.Text = 'Link 1';
            app.Link1Knob_2 = uiknob(app.PandaLinksTab, 'continuous');
            app.Link1Knob_2.Limits = [-157 157];
            app.Link1Knob_2.MajorTicks = [-157 -100 -50 0 50 100 157];
            app.Link1Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link1Knob_2ValueChanging, true);
            app.Link2Knob_2Label = uilabel(app.PandaLinksTab);
            app.Link2Knob_2Label.Text = 'Link 2';
            app.Link2Knob_2 = uiknob(app.PandaLinksTab, 'continuous');
            app.Link2Knob_2.Limits = [-102 102];
            app.Link2Knob_2.MajorTicks = [-102 -75 -50 0 50 75 102];
            app.Link2Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link2Knob_2ValueChanging, true);
            app.Link3Knob_2Label = uilabel(app.PandaLinksTab);
            app.Link3Knob_2Label.Text = 'Link 3';
            app.Link3Knob_2 = uiknob(app.PandaLinksTab, 'continuous');
            app.Link3Knob_2.Limits = [-166 166];
            app.Link3Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link3Knob_2ValueChanging, true);
            app.Link4Knob_2Label = uilabel(app.PandaLinksTab);
            app.Link4Knob_2Label.Text = 'Link 4';
            app.Link4Knob_2 = uiknob(app.PandaLinksTab, 'continuous');
            app.Link4Knob_2.Limits = [-174 174];
            app.Link4Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link4Knob_2ValueChanging, true);
            app.Link5Knob_2Label = uilabel(app.PandaLinksTab);
            app.Link5Knob_2Label.Text = 'Link 5';
            app.Link5Knob_2 = uiknob(app.PandaLinksTab, 'continuous');
            app.Link5Knob_2.Limits = [-161 161];
            app.Link5Knob_2.MajorTicks = [-161 -75 0 75 161];
            app.Link5Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link5Knob_2ValueChanging, true);
            app.Link6Knob_2Label = uilabel(app.PandaLinksTab);
            app.Link6Knob_2Label.Text = 'Link 6';
            app.Link6Knob_2 = uiknob(app.PandaLinksTab, 'continuous');
            app.Link6Knob_2.Limits = [-259 259];
            app.Link6Knob_2.MajorTicks = [-259 -150 0 150 259];
            app.Link6Knob_2.ValueChangingFcn = createCallbackFcn(app, @Link6Knob_2ValueChanging, true);
            app.Link7KnobLabel = uilabel(app.PandaLinksTab);
            app.Link7KnobLabel.Text = 'Link 7';
            app.Link7Knob = uiknob(app.PandaLinksTab, 'continuous');
            app.Link7Knob.Limits = [-173 173];
            app.Link7Knob.MajorTicks = [-173 -120 -50 0 50 120 173];
            app.Link7Knob.ValueChangingFcn = createCallbackFcn(app, @Link7KnobValueChanging, true);
            app.UR3CartesianControlTab = uitab(app.TabGroup);
            app.UR3CartesianControlTab.Title = 'UR3 Cartesian Control';
            app.XSliderLabel = uilabel(app.UR3CartesianControlTab);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UR3CartesianControlTab);
            app.XSlider.Limits = [-2.5 2.5];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.YSliderLabel = uilabel(app.UR3CartesianControlTab);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UR3CartesianControlTab);
            app.YSlider.Limits = [-2.5 2.5];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.ZSliderLabel = uilabel(app.UR3CartesianControlTab);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UR3CartesianControlTab);
            app.ZSlider.Limits = [-2.5 2.5];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.PandaCartesianControlTab = uitab(app.TabGroup);
            app.PandaCartesianControlTab.Title = 'Panda Cartesian Control';
            app.XSlider_2Label = uilabel(app.PandaCartesianControlTab);
            app.XSlider_2Label.Text = 'X';
            app.XSlider_2 = uislider(app.PandaCartesianControlTab);
            app.XSlider_2.Limits = [-2.5 2.5];
            app.XSlider_2.ValueChangingFcn = createCallbackFcn(app, @XSlider_2ValueChanging, true);
            app.YSlider_2Label = uilabel(app.PandaCartesianControlTab);
            app.YSlider_2Label.Text = 'Y';
            app.YSlider_2 = uislider(app.PandaCartesianControlTab);
            app.YSlider_2.Limits = [-2.5 2.5];
            app.YSlider_2.ValueChangingFcn = createCallbackFcn(app, @YSlider_2ValueChanging, true);
            app.ZSlider_2Label = uilabel(app.PandaCartesianControlTab);
            app.ZSlider_2Label.Text = 'Z';
            app.ZSlider_2 = uislider(app.PandaCartesianControlTab);
            app.ZSlider_2.Limits = [-2.5 2.5];
            app.ZSlider_2.ValueChangingFcn = createCallbackFcn(app, @ZSlider_2ValueChanging, true);
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.SafetyTestingPanel = uipanel(app.UIFigure);
            app.SafetyTestingPanel.Title = 'Safety Testing';
            app.VisualServoingDemoButton = uibutton(app.SafetyTestingPanel, 'push');
            app.VisualServoingDemoButton.ButtonPushedFcn = createCallbackFcn(app, @VisualServoingDemoButtonPushed, true);
            app.VisualServoingDemoButton.Text = 'Visual Servoing Demo';
            app.ShutDownButton = uibutton(app.UIFigure, 'push');
            app.ShutDownButton.ButtonPushedFcn = createCallbackFcn(app, @ShutDownButtonPushed, true);
            app.ShutDownButton.Text = 'Shut Down';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'Shutdown.png';
            app.ControllerStateLamp = uilamp(app.UIFigure);
            app.StatewindowEditField = uieditfield(app.UIFigure, 'text');
            app.EstopTriggerLamp = uilamp(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'SafeCo.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end